classdef TennisProjectGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        TennisTrackerTab       matlab.ui.container.Tab
        Image                  matlab.ui.control.Image
        StereoCalibratorTab    matlab.ui.container.Tab
        GuideTextArea          matlab.ui.control.TextArea
        GuideTextAreaLabel     matlab.ui.control.Label
        UITable                matlab.ui.control.Table
        PropogationErrorLabel  matlab.ui.control.Label
        BoardPlacementVisualizationLabel  matlab.ui.control.Label
        RefreshButton          matlab.ui.control.Button
        AddImagesButton        matlab.ui.control.Button
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
        AccuracyPlotsTab       matlab.ui.container.Tab
        SurfaceDropDown        matlab.ui.control.DropDown
        SurfaceDropDownLabel   matlab.ui.control.Label
        CORTable               matlab.ui.control.Table
        MeasureCORButton       matlab.ui.control.Button
        BaselineCORButton      matlab.ui.control.Button
        CORAxes                matlab.ui.control.UIAxes
        RawDataTab             matlab.ui.container.Tab
        PlaybackTab            matlab.ui.container.Tab
        SettingsTab            matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Property % Description
        LeftImageFolderPath  % Path to selected Left Images
        RightImageFolderPath % Path to selected Right Images
        CORFolderPath string % Path to COR Scripts
    end
    
    methods (Access = private)
%----------------------------------------------------------------------------------------------------------------------------------------------------------------
        function plotExtrinsics(app, stereoParams)
         tempFig = figure('Visible', 'off');
         showExtrinsics(stereoParams, 'CameraCentric');
         tempAx = gca;

         cla(app.UIAxes_2);
            copyobj(allchild(tempAx), app.UIAxes_2);
         app.UIAxes_2.XLim = tempAx.XLim;
         app.UIAxes_2.YLim = tempAx.YLim;
         app.UIAxes_2.ZLim = tempAx.ZLim;
         app.UIAxes_2.XColor = [1 1 1];
         app.UIAxes_2.YColor = [1 1 1];
            app.UIAxes_2.ZColor = [1 1 1];
            app.UIAxes_2.Box = 'on';
            close(tempFig);
        end

%----------------------------------------------------------------------------------------------------------------------------------------------------------------
        function plotReprojectionErrors(app, stereoParams)
             tempFig = figure('Visible', 'off');
             showReprojectionErrors(stereoParams);
            tempAx = gca;

            cla(app.UIAxes);
            copyobj(allchild(tempAx), app.UIAxes);
            app.UIAxes.XLim = tempAx.XLim;
            app.UIAxes.YLim = tempAx.YLim;
            app.UIAxes.XColor = [1 1 1];
            app.UIAxes.YColor = [1 1 1];
            app.UIAxes.Box = 'on';
            close(tempFig);
        end

%----------------------------------------------------------------------------------------------------------------------------------------------------------------   
        function [stereoParams, I1, I2, estimationErrors] = calibrateStereoCameras(app)
            % List all .png images in each selected folder
leftFiles = dir(fullfile(app.LeftImageFolderPath, '*.png'));
rightFiles = dir(fullfile(app.RightImageFolderPath, '*.png'));

% Sort by filename to maintain order
leftFiles = sort({leftFiles.name});
rightFiles = sort({rightFiles.name});

% Check for matching file counts
if numel(leftFiles) ~= numel(rightFiles)
    uialert(app.UIFigure, 'Left and Right image folders must have the same number of images.', 'Image Count Mismatch');
    error('Image count mismatch between left and right folders.');
end

% Construct full file paths
imageFileNames1 = fullfile(app.LeftImageFolderPath, leftFiles);
imageFileNames2 = fullfile(app.RightImageFolderPath, rightFiles);


            detector = vision.calibration.stereo.CheckerboardDetector();
             [imagePoints, ~] = detectPatternPoints(detector, imageFileNames1, imageFileNames2);

            squareSize = 900;  % millimeters
            worldPoints = generateWorldPoints(detector, 'SquareSize', squareSize);

             I1 = imread(imageFileNames1{1});
             I2 = imread(imageFileNames2{1});
            [mrows, ncols, ~] = size(I1);

            [stereoParams, ~, estimationErrors] = estimateCameraParameters(imagePoints, worldPoints, ...
                'EstimateSkew', false, ...
                'EstimateTangentialDistortion', false, ...
                'NumRadialDistortionCoefficients', 2, ...
                'WorldUnits', 'millimeters', ...
                'ImageSize', [mrows, ncols]);
        end
%----------------------------------------------------------------------------------------------------------------------------------------------------------------
        function updateCameraTable(app, stereoParams)
    % Camera positions in world coordinates
    center1 = [0, 0, 0];
    R2 = stereoParams.RotationOfCamera2;
    T2 = stereoParams.TranslationOfCamera2';
    center2 = -R2' * T2;

    f1 = mean(stereoParams.CameraParameters1.FocalLength);
    f2 = mean(stereoParams.CameraParameters2.FocalLength);

    app.UITable.Data = {
        'Left',  center1(1), center1(2), center1(3), f1;
        'Right', center2(1), center2(2), center2(3), f2;
    };
end
%----------------------------------------------------------------------------------------------------------------------------------------------------------------

            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            thisFile = mfilename('fullpath');
            appRoot = fileparts(thisFile);
            app.CORFolderPath = fullfile(appRoot, "CoefficientRestitution");
            if ~exist(app.CORFolderPath, "dir")
                uialert(app.UIFigure, "CoefficientRestitution folder not found.", "Path Error");
            else
                addpath(app.CORFolderPath);      % makes baselineCOR / measureCOR visible
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            [stereoParams, I1, I2, estimationErrors] = calibrateStereoCameras(app);
            plotReprojectionErrors(app, stereoParams);
            plotExtrinsics(app, stereoParams);
            updateCameraTable(app, stereoParams);


        end

        % Button pushed function: AddImagesButton
        function AddImagesButtonPushed(app, event)
            % BLOCKING: Prompt before selecting left folder
    choice = questdlg('Please select the LEFT camera image folder.', ...
                      'Select Left Folder', ...
                      'Continue', 'Cancel', 'Continue');

    if ~strcmp(choice, 'Continue')
        return;
    end

    leftDir = uigetdir(pwd, 'Select Left Camera Image Folder');
    if leftDir == 0
        return;
    end
    app.LeftImageFolderPath = leftDir;

    % BLOCKING: Prompt before selecting right folder
    choice = questdlg('Now select the RIGHT camera image folder.', ...
                      'Select Right Folder', ...
                      'Continue', 'Cancel', 'Continue');

    if ~strcmp(choice, 'Continue')
        return;
    end

    rightDir = uigetdir(pwd, 'Select Right Camera Image Folder');
    if rightDir == 0
        return;
    end
    app.RightImageFolderPath = rightDir;

    % Final confirmation
    uialert(app.UIFigure, 'Image folders loaded. You may now click Refresh.', 'Success');
        end

        % Button pushed function: BaselineCORButton
        function BaselineCORButtonPushed(app, event)
            runBaselineCOR(app.CORAxes,4,app);
        end

        % Button pushed function: MeasureCORButton
        function MeasureCORButtonPushed(app, event)
            surface = app.SurfaceDropDown.Value;
            try
                e = measureCOR(surface, app);
            catch ME
                uialert(app.UIFigure, ME.message, "COR Test Error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TennisTrackerTab = uitab(app.TabGroup);
            app.TennisTrackerTab.Title = 'Tennis Tracker';
            app.Image = uiimage(app.TennisTrackerTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'wiisportscourt.jpg');
            app.StereoCalibratorTab = uitab(app.TabGroup);
            app.StereoCalibratorTab.Title = 'Stereo Calibrator';
            app.UIAxes = uiaxes(app.StereoCalibratorTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.StereoCalibratorTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.AddImagesButton = uibutton(app.StereoCalibratorTab, 'push');
            app.AddImagesButton.ButtonPushedFcn = createCallbackFcn(app, @AddImagesButtonPushed, true);
            app.AddImagesButton.Text = 'Add Images';
            app.RefreshButton = uibutton(app.StereoCalibratorTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.BoardPlacementVisualizationLabel = uilabel(app.StereoCalibratorTab);
            app.BoardPlacementVisualizationLabel.Text = 'Board Placement Visualization';
            app.PropogationErrorLabel = uilabel(app.StereoCalibratorTab);
            app.PropogationErrorLabel.Text = 'Propogation Error';
            app.UITable = uitable(app.StereoCalibratorTab);
            app.UITable.ColumnName = {'Camera'; 'X'; 'Y'; 'Z'; 'f'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.GuideTextAreaLabel = uilabel(app.StereoCalibratorTab);
            app.GuideTextAreaLabel.Text = 'Guide';
            app.GuideTextArea = uitextarea(app.StereoCalibratorTab);
            app.GuideTextArea.Value = {'Please add file path for left and right images'};
            app.AccuracyPlotsTab = uitab(app.TabGroup);
            app.AccuracyPlotsTab.Title = 'Accuracy Plots';
            app.CORAxes = uiaxes(app.AccuracyPlotsTab);
            app.BaselineCORButton = uibutton(app.AccuracyPlotsTab, 'push');
            app.BaselineCORButton.ButtonPushedFcn = createCallbackFcn(app, @BaselineCORButtonPushed, true);
            app.BaselineCORButton.Text = 'Baseline COR';
            app.MeasureCORButton = uibutton(app.AccuracyPlotsTab, 'push');
            app.MeasureCORButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureCORButtonPushed, true);
            app.MeasureCORButton.Text = 'Measure COR';
            app.CORTable = uitable(app.AccuracyPlotsTab);
            app.CORTable.ColumnName = {'Surface'; 'Bounce #'; 'Velocity'; 'E'};
            app.CORTable.RowName = {};
            app.SurfaceDropDownLabel = uilabel(app.AccuracyPlotsTab);
            app.SurfaceDropDownLabel.Text = 'Surface';
            app.SurfaceDropDown = uidropdown(app.AccuracyPlotsTab);
            app.SurfaceDropDown.Items = {'Clay', 'Grass', 'Hard'};
            app.SurfaceDropDown.Value = 'Clay';
            app.RawDataTab = uitab(app.TabGroup);
            app.RawDataTab.Title = 'Raw Data';
            app.PlaybackTab = uitab(app.TabGroup);
            app.PlaybackTab.Title = 'Playback';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TennisProjectGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end